classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EditTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        EditingJointDropDownLabel      matlab.ui.control.Label
        EditingJointDropDown           matlab.ui.control.DropDown
        InfoTextAreaLabel              matlab.ui.control.Label
        InfoTextArea                   matlab.ui.control.TextArea
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ApplyButton                    matlab.ui.control.Button
        DeleteConnectionDropDownLabel  matlab.ui.control.Label
        DeleteConnectionDropDown       matlab.ui.control.DropDown
        AddConnectionDropDownLabel     matlab.ui.control.Label
        AddConnectionDropDown          matlab.ui.control.DropDown
        AddJointButton                 matlab.ui.control.Button
        DeleteJointButton              matlab.ui.control.Button
        DeletDropDown                  matlab.ui.control.DropDown
        InputFromFIleButton            matlab.ui.control.Button
        matEditFieldLabel              matlab.ui.control.Label
        matEditField                   matlab.ui.control.EditField
        UIAxes_3                       matlab.ui.control.UIAxes
        WriteToFileButton              matlab.ui.control.Button
        SimulateTab                    matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        PinJointDropDownLabel          matlab.ui.control.Label
        PinJointDropDown               matlab.ui.control.DropDown
        RollerJointDropDownLabel       matlab.ui.control.Label
        RollerJointDropDown            matlab.ui.control.DropDown
        LoadingJointDropDownLabel      matlab.ui.control.Label
        LoadingJointDropDown           matlab.ui.control.DropDown
        ColorRepresentingButtonGroup   matlab.ui.container.ButtonGroup
        ForceinNButton                 matlab.ui.control.RadioButton
        PotentialofFailingButton       matlab.ui.control.RadioButton
        LoadNSpinnerLabel              matlab.ui.control.Label
        LoadNSpinner                   matlab.ui.control.Spinner
        InfoTextArea_2Label            matlab.ui.control.Label
        InfoTextArea_2                 matlab.ui.control.TextArea
        Button                         matlab.ui.control.Button
        MatrixTab                      matlab.ui.container.Tab
    end

    
    properties (Access = public)
        A
        C
        X
        Y
        L
        Sx
        Sy
        T
        fileName
        editingJ
        textInfo
        sizeR
        sizeC
        
    end
    
    methods (Access = private)
        %=====================================================
        
        
        %=========================================================
        
        
        function plotStr(app)
           hold(app.UIAxes_3,'on');
           
        
           cla(app.UIAxes_3);
           axis(app.UIAxes_3,[min(app.X)-3,max(app.X)+3,-3-(max(app.X)-min(app.X))/2,3+(max(app.X)-min(app.X))/2]);

           
            for i = 1:length(app.X)
            plot(app.UIAxes_3,app.X(i),app.Y(i),'ro');
            lable = sprintf('J# %d',i);
            text(app.UIAxes_3,app.X(i)-0.2,app.Y(i)-0.2,lable,'FontSize',15);
            end
            
            [app.sizeR,app.sizeC]=size(app.C);
            for j = 1:app.sizeC
            plotingJ = find(app.C(:,j)==1);
            plotingX = [app.X(plotingJ(1)),app.X(plotingJ(2))];
            plotingY = [app.Y(plotingJ(1)),app.Y(plotingJ(2))];
            lineHandle = plot(app.UIAxes_3,plotingX,plotingY,'b-');
            lineHandle.LineWidth = 1;
            
            lineLable = sprintf('S# %d',j);
            text(app.UIAxes_3,mean(plotingX)-0.15,mean(plotingY)-0.15,lineLable,'FontSize',15);

            end
  
        end
    
            
        function plotSimu(app)
           hold(app.UIAxes_2,'on');
           
           app.calMatrix();

        
           cla(app.UIAxes_2);
           axis(app.UIAxes_2,[min(app.X)-3,max(app.X)+3,-3-(max(app.X)-min(app.X))/2,3+(max(app.X)-min(app.X))/2]);

           
            for i = 1:length(app.X)
            plot(app.UIAxes_2,app.X(i),app.Y(i),'ro');
            lable = sprintf('%d',i);
            text(app.UIAxes_2,app.X(i)-0.15,app.Y(i)-0.15,lable);
            end
            
            [app.sizeR,app.sizeC]=size(app.C);
            for j = 1:app.sizeC
            plotingJ = find(app.C(:,j)==1);
            plotingX = [app.X(plotingJ(1)),app.X(plotingJ(2))];
            plotingY = [app.Y(plotingJ(1)),app.Y(plotingJ(2))];
            lineHandle = plot(app.UIAxes_2,plotingX,plotingY,'-b');
            lineHandle.LineWidth = 1.5;
            if (app.ForceinNButton.Value)
            lineColor = determineColor_N(app,j);
            lineHandle.Color = lineColor;
            elseif(app.PotentialofFailingButton.Value)
            lineColor = determineColor_Poten(app,j);
            lineHandle.Color = lineColor;
            end
            
            lineLable = sprintf('S# %d',j);
            text(app.UIAxes_2,mean(plotingX)-0.15,mean(plotingY)-0.15,lineLable);
            
            end
  
        end
    
    %======================================================
        function calMatrix(app)
            
            num_Joint = 0;
            num_Con = 0;
            [num_Joint,num_Con]=size(app.C);
            
            app.Sx=zeros(num_Joint,3);
            app.Sy=zeros(num_Joint,3);
            app.L = zeros(2*num_Joint,1);
            
            %==========================================
            app.L(num_Joint+7)=25;
            app.Sx(1,1) = 1;
            app.Sy(1,2) = 1;
            app.Sy(2,3) = 1;
            %=====================================================
            
            C = app.C;
            X = app.X;
            Y = app.Y;
            Sx = app.Sx;
            Sy = app.Sy;
            L = app.L;
            
            
            A=zeros(num_Joint*2,num_Con+3);
 
            for i = 1:num_Joint
                mConnected = find(C(i,:) == 1);
                   for j = mConnected
                        alljConnected=find(C(:,j) == 1);
                        jConnected=alljConnected(alljConnected~=i);
                        x1=X(i);
                        x2=X(jConnected);
                        y1=Y(i);
                        y2=Y(jConnected);
                        unitVecX=(x2-x1)/sqrt((x2-x1)^2+(y2-y1)^2);
                        unitVecY=(y2-y1)/sqrt((x2-x1)^2+(y2-y1)^2);
                        A(i,j)=unitVecX;
                        A(i+num_Joint,j)=unitVecY;
                    end
             end
 
            A(1:num_Joint,num_Con+1:end)=Sx;
            A(num_Joint+1:end,num_Con+1:end)=Sy;
  
            T=inv(A)*L; 
            
            app.A = A;
            app.T = T;
           
          
        end
    
    
    %=====================================================================
        function Color = determineColor_N(app,C_NUM)
 
        red = 0;
        green = 0;
        blue = 0;
        force = app.T(C_NUM);
        if( force <= 0 )
        red = -force/20;
        green = 0;
        blue = 0;
        elseif (force > 0)
            red = 0;
            green = force/2/20;
            blue = force/20;
        end
        
           
           if red>1
              red=1;
           elseif red<0
               red = 0;
           end
           if blue>1
              blue=1;
           elseif blue<0
               blue = 0;
           end
           if green>1
              green=1;
           elseif green<0
               green = 0;
           end
        
        Color = [red,green,blue];
    
        end
   
    
        function Color=determineColor_Poten(app,C_NUM)
                
       
        red = 0;
        green = 0;
        blue = 0;
        force = app.T(C_NUM);

        JJ = find(app.C(:,C_NUM) == 1);
        J1=JJ(1);
        J2=JJ(2);
        
        x1 = app.X(J1);
        x2 = app.X(J2);
        y1 = app.Y(J1);
        y2 = app.Y(J2);
        
        length = sqrt((x1-x2).^2+(y1-y2).^2);
        
        bucklF = 1327.9/(length.^2);
        
        if(force >= 0)
        red = 0;
        green = 0;
        blue = 0;
            
        elseif(force <0 && force > -bucklF/2)
           red = 0;
           green = 0;
           blue = (bucklF/2 - (-force))/(bucklF/2);

           
        else
            red = ((-force)-(bucklF/2))/(bucklF/2);
            green = 0;
            blue = 0;
        end
           if red>1
              red=1;
           elseif red<0
               red = 0;
           end
           if blue>1
              blue=1;
           elseif blue<0
               blue = 0;
           end
           if green>1
              green=1;
           elseif green<0
               green = 0;
           end

            Color = [red,green,blue];
    
        end
 %==================================================================
 %=================================================================
        function updateJ_Num(app)

        [app.sizeR,app.sizeC]=size(app.C);
        app.AddJointButton.Text = sprintf('Add Joint: %d',app.sizeR+1);
       %====================================================
         app.DeletDropDown.Items = {};
         for i=1:app.sizeR
             item = sprintf('Joint#%d',i);
            app.DeletDropDown.Items{end+1} = item;
         end
         app.DeletDropDown.ItemsData = 1:app.sizeR;
        %===================================================
         app.AddConnectionDropDown.Items = {'None'}; 
         for i=1:app.sizeR
             item = sprintf('Joint#%d',i);
            app.AddConnectionDropDown.Items{end+1} = item;
         end
         app.AddConnectionDropDown.ItemsData = [0,1:app.sizeR];
        
         CC=app.C;
       save('testtttt.mat','CC');
        end
    
    
    %=======================================
    function upDateEditing_Bar(app)
         [app.sizeR,app.sizeC]=size(app.C);
          app.EditingJointDropDown.Items = {};
         for i=1:app.sizeR
             item = sprintf('Joint#%d',i);
            app.EditingJointDropDown.Items{end+1} = item;
         end
         app.EditingJointDropDown.ItemsData = 1:app.sizeR;
         
         app.updateJ_Num();
     
    end


%==========================================
        function upDateDeleting_Bar(app)
            
            value = app.EditingJointDropDown.Value;
                       
            connectedC = find(app.C(value,:) == 1);
            app.DeleteConnectionDropDown.Items = {'None'};
            
            itemsD=[0];
            
            for i = connectedC
               AllconnectedJ = find(app.C(:,i) == 1);
               connectedJ = AllconnectedJ(AllconnectedJ~=value);
               app.DeleteConnectionDropDown.Items{end+1} = sprintf('Joint#%d',connectedJ);
               itemsD = [itemsD,connectedJ];
            end
            app.DeleteConnectionDropDown.ItemsData = itemsD; 
        end


%=====================
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           % app.UIAxes.XTick = 0:10:60;
            %app.UIAxes.XTickLabel = 0:10:60;
            %app.UIAxes.YTick = -20:10:20;
            %app.UIAxes.YTickLabel = -20:10:20;
            app.sizeR = 0;
            app.sizeC = 0;
            app.EditingJointDropDown.Items = {};
            app.updateJ_Num();
            hold(app.UIAxes_3,'on');
            
                  CC=app.C;
       save('testtttt.mat','CC');


        end

        % Button pushed function: AddJointButton
        function AddJointButtonPushed(app, event)

            app.C = [app.C;zeros(1,app.sizeC)];
           [app.sizeR,app.sizeC]=size(app.C);
            app.X = [app.X, 0];
            app.Y = [app.Y, 0];
          
            app.EditingJointDropDown.Items{end+1} = sprintf('Joint#%d',app.sizeR);
            app.EditingJointDropDown.ItemsData = [app.EditingJointDropDown.ItemsData,app.sizeR];
                 
            app.updateJ_Num();
            
            
            app.plotStr();
                    CC=app.C;
       save('testtttt.mat','CC');
        end

        % Value changed function: EditingJointDropDown
        function EditingJointDropDownValueChanged(app, event)
            value = app.EditingJointDropDown.Value;
            app.XEditField.Value = app.X(value);
            app.YEditField.Value = app.Y(value);
            
            connectedC = find(app.C(value,:) == 1);
            app.DeleteConnectionDropDown.Items = {'None'};
            
            itemsD=[0];
            
            for i = connectedC
               AllconnectedJ = find(app.C(:,i) == 1);
               connectedJ = AllconnectedJ(AllconnectedJ~=value);
               app.DeleteConnectionDropDown.Items{end+1} = sprintf('Joint#%d',connectedJ);
               itemsD = [itemsD,connectedJ];
            end
            app.DeleteConnectionDropDown.ItemsData = itemsD;
           
            app.plotStr();
                     CC=app.C;
       save('testtttt.mat','CC');
            
            
        end

        % Button pushed function: DeleteJointButton
        function DeleteJointButtonPushed(app, event)
           if app.sizeR ~= 0
               
            deletJ = app.DeletDropDown.Value;
            deletC = find(app.C(deletJ,:) == 1);
            
            app.C(deletJ,:) = [];
            
            deletC = sort(deletC,'descend');
            for i = deletC
            app.C(:,i) = [];
            end
            
            [app.sizeR,app.sizeC]=size(app.C);
            
            app.X(deletJ)=[];
            app.Y(deletJ)=[];
            
         app.EditingJointDropDown.Items = {};
         for i=1:app.sizeR
             item = sprintf('Joint#%d',i);
            app.EditingJointDropDown.Items{end+1} = item;
         end
         app.EditingJointDropDown.ItemsData = 1:app.sizeR;
         
         app.updateJ_Num();
         
           end
           
           
           app.plotStr();
                    CC=app.C;
       save('testtttt.mat','CC');
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
           [app.sizeR,app.sizeC]=size(app.C);
           
           changeJ = app.EditingJointDropDown.Value;
           app.X(changeJ) = app.XEditField.Value;
           app.Y(changeJ) = app.YEditField.Value;
           
           
            if(app.AddConnectionDropDown.Value ~= 0)
                joint1 = app.EditingJointDropDown.Value;
                joint2 = app.AddConnectionDropDown.Value;
                app.C = [app.C zeros(app.sizeR,1)];
                app.C(joint1,end) = 1;
                app.C(joint2,end) = 1;
                app.updateJ_Num();
            end
            
            if(app.DeleteConnectionDropDown.Value ~= 0)
                Djoint1 = app.EditingJointDropDown.Value;
                Djoint2 = app.DeleteConnectionDropDown.Value;
                
                for i = app.sizeC:-1:1
                    if (app.C(Djoint1,i) == 1) && (app.C(Djoint2,i) == 1)
                        app.C(:,i)=[];
                    end
                end
             app.updateJ_Num();
            end
            
            app.plotStr();
            app.updateJ_Num();
            app.upDateDeleting_Bar();
            
                 CC=app.C;
       save('testtttt.mat','CC');
        end

        % Button pushed function: InputFromFIleButton
        function InputFromFIleButtonPushed(app, event)
            filename = sprintf('%s.mat',app.matEditField.Value);
            load(filename,'A','C','X','Y','Sx','Sy','L','T');
            app.C=C;
            app.X=X;
            app.Y=Y;
            app.Sx = Sx;
            app.Sy = Sy;
            app.L = L;
            
            app.updateJ_Num();
            app.upDateEditing_Bar();
            app.upDateDeleting_Bar();
            app.plotStr();
            
        end

        % Button pushed function: WriteToFileButton
        function WriteToFileButtonPushed(app, event)
            A = app.A;
            C = app.C;
            X = app.X;
            Y = app.Y;
            Sx = app.Sx;
            Sy = app.Sy;
            L = app.L;
            T = app.T;
       
            filename = sprintf('%s.mat',app.matEditField.Value);
            save(filename,'A','C','X','Y','Sx','Sy','L','T');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.plotSimu();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EditTab = uitab(app.TabGroup);
            app.EditTab.Title = 'Edit';
            app.UIAxes = uiaxes(app.EditTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 10 20 30 40 50 60];
            app.UIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'};
            app.UIAxes.YTick = [-20 -10 0 10 20];
            app.UIAxes.YTickLabel = {'-20'; '-10'; '0'; '10'; '20'};
            app.EditingJointDropDownLabel = uilabel(app.EditTab);
            app.EditingJointDropDownLabel.Text = 'Editing Joint';
            app.EditingJointDropDown = uidropdown(app.EditTab);
            app.EditingJointDropDown.ValueChangedFcn = createCallbackFcn(app, @EditingJointDropDownValueChanged, true);
            app.InfoTextAreaLabel = uilabel(app.EditTab);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.EditTab);
            app.InfoTextArea.Editable = 'off';
            app.XEditFieldLabel = uilabel(app.EditTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EditTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.EditTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EditTab, 'numeric');
            app.ApplyButton = uibutton(app.EditTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DeleteConnectionDropDownLabel = uilabel(app.EditTab);
            app.DeleteConnectionDropDownLabel.Text = 'Delete Connection';
            app.DeleteConnectionDropDown = uidropdown(app.EditTab);
            app.AddConnectionDropDownLabel = uilabel(app.EditTab);
            app.AddConnectionDropDownLabel.Text = 'Add Connection';
            app.AddConnectionDropDown = uidropdown(app.EditTab);
            app.AddJointButton = uibutton(app.EditTab, 'push');
            app.AddJointButton.ButtonPushedFcn = createCallbackFcn(app, @AddJointButtonPushed, true);
            app.AddJointButton.Text = 'Add Joint:';
            app.DeleteJointButton = uibutton(app.EditTab, 'push');
            app.DeleteJointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteJointButtonPushed, true);
            app.DeleteJointButton.Text = 'Delete Joint';
            app.DeletDropDown = uidropdown(app.EditTab);
            app.InputFromFIleButton = uibutton(app.EditTab, 'push');
            app.InputFromFIleButton.ButtonPushedFcn = createCallbackFcn(app, @InputFromFIleButtonPushed, true);
            app.InputFromFIleButton.Text = 'Input From FIle';
            app.matEditFieldLabel = uilabel(app.EditTab);
            app.matEditFieldLabel.Text = '.mat';
            app.matEditField = uieditfield(app.EditTab, 'text');
            app.UIAxes_3 = uiaxes(app.EditTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.WriteToFileButton = uibutton(app.EditTab, 'push');
            app.WriteToFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteToFileButtonPushed, true);
            app.WriteToFileButton.Text = 'Write To File';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.UIAxes_2 = uiaxes(app.SimulateTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.PinJointDropDownLabel = uilabel(app.SimulateTab);
            app.PinJointDropDownLabel.Text = 'Pin Joint';
            app.PinJointDropDown = uidropdown(app.SimulateTab);
            app.RollerJointDropDownLabel = uilabel(app.SimulateTab);
            app.RollerJointDropDownLabel.Text = 'Roller Joint';
            app.RollerJointDropDown = uidropdown(app.SimulateTab);
            app.LoadingJointDropDownLabel = uilabel(app.SimulateTab);
            app.LoadingJointDropDownLabel.Text = 'Loading Joint';
            app.LoadingJointDropDown = uidropdown(app.SimulateTab);
            app.ColorRepresentingButtonGroup = uibuttongroup(app.SimulateTab);
            app.ColorRepresentingButtonGroup.Title = 'Color Representing';
            app.ForceinNButton = uiradiobutton(app.ColorRepresentingButtonGroup);
            app.ForceinNButton.Text = 'Force in N';
            app.ForceinNButton.Value = true;
            app.PotentialofFailingButton = uiradiobutton(app.ColorRepresentingButtonGroup);
            app.PotentialofFailingButton.Text = 'Potential of Failing';
            app.LoadNSpinnerLabel = uilabel(app.SimulateTab);
            app.LoadNSpinnerLabel.Text = 'Load (N)';
            app.LoadNSpinner = uispinner(app.SimulateTab);
            app.InfoTextArea_2Label = uilabel(app.SimulateTab);
            app.InfoTextArea_2Label.Text = 'Info';
            app.InfoTextArea_2 = uitextarea(app.SimulateTab);
            app.InfoTextArea_2.Editable = 'off';
            app.Button = uibutton(app.SimulateTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.MatrixTab = uitab(app.TabGroup);
            app.MatrixTab.Title = 'Matrix';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end